classdef App05PVU20 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes3_Label                matlab.ui.control.Label
        Eur_cfw_Value                matlab.ui.control.Label
        Eur_s_Value                  matlab.ui.control.Label
        E_LE_Slider_Label            matlab.ui.control.Label
        E_LE_Slider                  matlab.ui.control.Slider
        Autarkiegrad_Value           matlab.ui.control.Label
        Eigenverbrauchsanteil_Value  matlab.ui.control.Label
        Zeilennr_Value               matlab.ui.control.Label
        P_PV_Slider_Label            matlab.ui.control.Label
        P_PV_Slider                  matlab.ui.control.Slider
        Ueberschrift_Label           matlab.ui.control.Label
        Eur_bez_DropDown             matlab.ui.control.DropDown
        Label                        matlab.ui.control.Label
        Eur_inv_DropDown             matlab.ui.control.DropDown
        InvestitionenLabel           matlab.ui.control.Label
        E_BAT_Slider_Label           matlab.ui.control.Label
        E_BAT_Slider                 matlab.ui.control.Slider
        Eur_lau_DropDown             matlab.ui.control.DropDown
        LaufendeKostenLabel          matlab.ui.control.Label
        Eur_ein_DropDown             matlab.ui.control.DropDown
        EinspeisevergtungLabel       matlab.ui.control.Label
        BatteriesystemLabel          matlab.ui.control.Label
        E_BAT_Switch                 matlab.ui.control.Switch
        EigenverbrauchLabel          matlab.ui.control.Label
        E_LE_Switch                  matlab.ui.control.Switch
        Eur_bez_DropDown_Label       matlab.ui.control.Label
        AusrichtungLabel             matlab.ui.control.Label
        AZI_PV_DropDown              matlab.ui.control.DropDown
        Erzeugung_PV_Value           matlab.ui.control.Label
        Netzbezug_Value              matlab.ui.control.Label
        Eclo_Value                   matlab.ui.control.Label
        SwitchLabel                  matlab.ui.control.Label
        Eclo_Switch                  matlab.ui.control.Switch
        Label_2                      matlab.ui.control.Label
        Eclo_frei_DropDown           matlab.ui.control.DropDown
        Eclo_frei_DropDown_Label     matlab.ui.control.Label
        Eclo_Switch_Label            matlab.ui.control.Label
        HeizstabSwitchLabel          matlab.ui.control.Label
        HS_Switch                    matlab.ui.control.Switch
        HS_Value                     matlab.ui.control.Label
        WrmepumpeSwitchLabel         matlab.ui.control.Label
        E_WP_Switch                  matlab.ui.control.Switch
        E_WP_Slider_Label            matlab.ui.control.Label
        E_WP_Slider                  matlab.ui.control.Slider
        Erzeugung_PV_spez_Value      matlab.ui.control.Label
        E_BAT_socdur_Value           matlab.ui.control.Label
        ElektrofahrzeugSwitchLabel   matlab.ui.control.Label
        S_BEV_Switch                 matlab.ui.control.Switch
        S_BEV_DropDown_Label         matlab.ui.control.Label
        S_BEV_Value                  matlab.ui.control.Label
        S_BEV_DropDown               matlab.ui.control.DropDown
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Definition der Variablen, die nachfolgend genutzt werden
    properties (Access = private)
        ERG % Ergebnismatrix
        
        P_PV_v % Vektor mit allen Werten der PV-Generatorleistung
        P_PV_i % Schrittweite der PV-Generatorleistung
        P_PV_idx % Logischer Index der PV-Generatorleistung
        AZI_PV_v % Vektor mit allen Werten der PV-Generatorleistung
        AZI_PV_i % Schrittweite der PV-Generatorleistung
        AZI_PV_idx % Logischer Index der Ausrichtung
        E_LE_v % Vektor mit allen Werten des Hausstromverbrauchs
        E_LE_i % Schrittweite des Hausstromverbrauchs
        E_LE_idx % Logischer Index des Hausstromverbrauchs
        E_BAT_v % Vektor mit allen Werten der Batteriekapazitaet
        E_BAT_i % Schrittweite der Batteriekapazitaet
        E_BAT_idx % Logischer Index der Batteriekapazitaet
        Eclo_frei_v % Vektor mit allen Werten der Cloud
        Eclo_frei_i % Schrittweite der Cloud
        Eclo_frei_idx % Logischer Index der Cloud
        HS_v % Vektor mit allen Werten des Heizstabs
        HS_i % Schrittweite des Heizstabs
        HS_idx % Logischer Index des Heizstabs
        E_WP_v % Vektor mit allen Werten des WPstromverbrauchs
        E_WP_i % Schrittweite des WPstromverbrauchs
        E_WP_idx % Logischer Index des WPstromverbrauchs
        S_BEV_v % Vektor mit allen Werten der Fahrstrecke
        S_BEV_i % Schrittweite der Fahrstrecke
        S_BEV_idx % Logischer Index der Fahrstrecke
        Eur_ein_v % Vektor mit allen Werten der Einspeisevergütung
        Eur_ein_i % Schrittweite der Einspeisevergütung
        Eur_ein_idx % Logischer Index der Einspeisevergütung
        Eur_bez_v % Vektor mit allen Werten des Bezugspreises
        Eur_bez_i % Schrittweite des Bezugspreises
        Eur_bez_idx % Logischer Index des Bezugspreises
        Eur_lau_v % Vektor mit allen Werten der laufenden Kosten
        Eur_lau_i % Schrittweite der laufenden Kosten
        Eur_lau_idx % Logischer Index der laufenden Kosten
        Eur_inv_v % Vektor mit allen Werten der Investitionssumme
        Eur_inv_i % Schrittweite der Investitionssumme
        Eur_inv_idx % Logischer Index der Investitionssumme
        
        znr_idx % Logischer Index der Zeilennummer der aktuellen Variante
        znr % Zeilennummer der aktuellen Variante
        
        c % Farben (engl. colours)
        p % Kreisdiagramm (engl. pie chart)
        b % Balkendiagramm (engl. bar chart)
        S % Struct fuer den Datenimport
    end 
    
    methods (Access = private)
            % Definition der Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse 
        function func(app)
            % Logische Indizes der einzelnen Variablen ermitteln: In welchen Zeilen
            % in der Ergebnismatrix kommen die aktuellen Slider-Werte vor?
            app.P_PV_idx = app.ERG(:,2) == app.P_PV_Slider.Value;
            app.AZI_PV_idx = app.ERG(:,3) == app.AZI_PV_DropDown.Value;
            app.E_LE_idx = app.ERG(:,4) == app.E_LE_Slider.Value;
            app.E_BAT_idx = app.ERG(:,5) == app.E_BAT_Slider.Value;
            app.Eclo_frei_idx = app.ERG(:,6) == app.Eclo_frei_DropDown.Value;
            app.HS_idx = app.ERG(:,7) == app.HS_Switch.Value;
            app.E_WP_idx = app.ERG(:,8) == app.E_WP_Slider.Value;
            app.S_BEV_idx = app.ERG(:,9) == app.S_BEV_DropDown.Value;
            app.Eur_ein_idx = app.ERG(:,10) == app.Eur_ein_DropDown.Value;
            app.Eur_bez_idx = app.ERG(:,11) == app.Eur_bez_DropDown.Value;
            app.Eur_lau_idx = app.ERG(:,12) == app.Eur_lau_DropDown.Value;
            app.Eur_inv_idx = app.ERG(:,13) == app.Eur_inv_DropDown.Value;
                        
            % Zeilennummer fuer die aktuelle Variante ermitteln: In welcher Zeile in
            % der Ergebnismatrix sind die Ergebnisse fuer die aktuelle Variante
            % aufgefuehrt?
                                                                                                                                                                       
            app.znr = sum(app.ERG(:,1).*app.P_PV_idx.*app.AZI_PV_idx.*app.E_LE_idx.*app.E_BAT_idx.*app.Eclo_frei_idx.*app.HS_idx.*app.E_WP_idx.*app.S_BEV_idx.*app.Eur_ein_idx.*app.Eur_bez_idx.*app.Eur_lau_idx.*app.Eur_inv_idx); 
            
            % 0. Auffuehren Werte
            % Zur Pruefung der Zeilennummer: Nachfolgende Zeile aktivieren
            app.Zeilennr_Value.Text = ['Zeilennr.: ',num2str(app.znr)];
            % Erzeugug PV-Anlage auffuehren in kWh
            app.Erzeugung_PV_Value.Text = strrep(['PV Jahreserzeugung: ',num2str(app.ERG(app.znr,14),'%0.0f'),' kWh/a'], '.', ',');
            % Speziegische Erzeugug PV-Anlage auffuehren in kWh/kWp
            app.Erzeugung_PV_spez_Value.Text = strrep(['Speziefischer Ertrag: ',num2str(app.ERG(app.znr,34),'%0.0f'),' kWh/kWp'], '.', ',');
            % Netzbezug auffuehren in kWh
            app.Netzbezug_Value.Text = strrep(['Netzbezug: ',num2str(app.ERG(app.znr,30),'%0.0f'),' kWh/a'], '.', ',');
            % Durchschnittlicher Ladeszustand Batteriespeichers in %
            app.E_BAT_socdur_Value.Text = strrep(['Durchschnittlicher Ladeszustand des Batteriespeichers: ',num2str(app.ERG(app.znr,35),'%0.0f'),' %'], '.', ',');
            % Cloudnutzung in kWh
            app.Eclo_Value.Text = strrep(['Stromcloudeinspeisung: ',num2str(app.ERG(app.znr,22),'%0.0f'),' kWh/a'], '.', ',');
            % Heizstabnutzung in kWh
            app.HS_Value.Text = strrep(['Heizstabnutzung: ',num2str(app.ERG(app.znr,17),'%0.0f'),' kWh/a'], '.', ',');
            % Brutto Energiebedarf BEV Zuhause in kWh
            app.S_BEV_Value.Text = strrep(['Brutto Energiebedarf Zuhause: ',num2str(app.ERG(app.znr,38),'%0.0f'),' kWh/a'], '.', ',');
            % Eigenverbrauchsanteil auffuehren in %
            app.Eigenverbrauchsanteil_Value.Text = strrep(['Eigenverbrauchsanteil: ',num2str(app.ERG(app.znr,37),'%0.1f'),' %'], '.', ',');
            % Autarkiegrad auffuehren in %
            app.Autarkiegrad_Value.Text = strrep(['Autarkiegrad: ',num2str(app.ERG(app.znr,36),'%0.1f'),' %'], '.', ',');  
            % Ersparniss im ersten Jahr auffuehren in €
            Eur_s_j1 = app.ERG(app.znr,39)+app.ERG(app.znr,40)+app.ERG(app.znr,41);
            app.Eur_s_Value.Text = strrep(['Ersparniss pro Jahr: ',num2str(Eur_s_j1,'%0.0f'),' €/a'], '.', ',');  
            % Cashflow nach 10 Jahren auffuehren in €
            app.Eur_cfw_Value.Text = strrep(['Cashflow nach 10 Jahren: ',num2str(-(app.ERG(app.znr,13)+app.ERG(app.znr,12)*120)+Eur_s_j1*10,'%0.0f'),' €'], '.', ','); 
            
            % 1. Kreisdiagramm: Solarstromnutzung
            % Zusammensetzung: PV-Direktversorgung Hauslast (6), PV-Direktversorgung WP (35),PV-Direktversorgung HS (33),
            % AC-Energieaufnahme des Batteriesystems (7), Einspeisung Stromcloud (31), Netzeinspeisung (9)
            
            app.UIAxes1.Title.String='Solarstromnutzung'; 
            Data1 = [app.ERG(app.znr,16),app.ERG(app.znr,17),app.ERG(app.znr,18), app.ERG(app.znr,19) ,app.ERG(app.znr,21),app.ERG(app.znr,22),app.ERG(app.znr,23)];
            labels1 = {strrep(['PV-Dir. Hauslast: ',        num2str(app.ERG(app.znr,16),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['PV-Dir. Heizstab: ',        num2str(app.ERG(app.znr,17),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['PV-Dir. Wärmepumpe: ',      num2str(app.ERG(app.znr,18),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['PV-Dir. Elektrofahrzeug: ', num2str(app.ERG(app.znr,19),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['PV-Batterieladung: ',       num2str(app.ERG(app.znr,21),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Cloudeinspeisung: ',        num2str(app.ERG(app.znr,22),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Netzeinspeisung: ',         num2str(app.ERG(app.znr,23),'%0.0f'),' kWh/a'], '.', ',')};
            colors1 = [app.c.gelb , app.c.o , app.c.or ,  app.c.lila , app.c.hgruen , app.c.blau , app.c.grau];
            nonZeroIndexes1 = Data1 > 0;
            n1 = sum(nonZeroIndexes1);
            nonZeroIndexes1_c = repmat(nonZeroIndexes1,3,1);
            nonZeroIndexes1_c = nonZeroIndexes1_c(:)';
            colors1 = colors1(nonZeroIndexes1_c);
            colors1 = reshape(colors1,  3, n1)';
            labels1 = labels1(nonZeroIndexes1);
            pieData1 =  Data1; 
            pieData1 = pieData1(nonZeroIndexes1); % Nur positive Zahlen extrahieren
            app.p = pie(app.UIAxes1,pieData1);
            app.UIAxes1.Children; % Umrandung entfernen
            for i=(2:2:(n1*2))
            app.UIAxes1.Children(i).EdgeAlpha = 0; 
            end
            % Mittlerer Kreis
            hold(app.UIAxes1, 'on')
            app.p=plot(app.UIAxes1,0 , 0, '.', 'MarkerSize', 400, 'color', [0.94,0.94,0.94]);
            colormap(app.UIAxes1,colors1);
            legend(app.UIAxes1,labels1,'Location','southoutside','Box','off','Color','none','FontSize',10)
            app.UIAxes1.XLim=[-1.5 1.5];
            app.UIAxes1.YLim=app.UIAxes1.XLim;
            hold(app.UIAxes1, 'off')
            
            % 2. Kreisdiagramm: Stromversorgung
            % Zusammensetzung: PV-Direktversorgung Hauslast (6), PV-Direktversorgung WP (35),PV-Direktversorgung HS (33),
            % AC-Energieabgabe BS an Hauslast (8), AC-Energieabgabe BS an WP (37), 
            % Bezug Stromcloud (31), Netzbezug Hauslast (38), Netzbezug WP (39),  
            
            if (app.E_LE_Slider.Value ~= 0)||(app.E_BAT_Slider.Value ~= 0)||(app.S_BEV_DropDown.Value ~= 0)||(app.HS_Switch.Value ~= 0)||(app.E_WP_Slider.Value ~= 0)
                
            app.UIAxes2.Title.String='Stromversorgung'; 
            Data2 = round([app.ERG(app.znr,16),app.ERG(app.znr,17),app.ERG(app.znr,18), app.ERG(app.znr,19) ,app.ERG(app.znr,25),app.ERG(app.znr,26),app.ERG(app.znr,27),app.ERG(app.znr,28),app.ERG(app.znr,29),app.ERG(app.znr,31),app.ERG(app.znr,32),app.ERG(app.znr,33)],0);
            labels2 = {strrep(['PV-Dir. Hauslast: ',            num2str(app.ERG(app.znr,16),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['PV-Dir. Heizstab: ',            num2str(app.ERG(app.znr,17),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['PV-Dir. Wärmepumpe: ',          num2str(app.ERG(app.znr,18),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['PV-Dir. Elektrofahrzeug: ',     num2str(app.ERG(app.znr,19),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Batterieentladung Hauslast: ',  num2str(app.ERG(app.znr,25),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Batterieentladung Wärmepumpe: ',num2str(app.ERG(app.znr,26),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Cloudbezug Hauslast: ',         num2str(app.ERG(app.znr,27),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Cloudbezug Wärmepumpe: ',       num2str(app.ERG(app.znr,28),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Cloudbezug Elektrofahrzeug: ',  num2str(app.ERG(app.znr,29),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Netzbezug Hauslast: ',          num2str(app.ERG(app.znr,31),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Netzbezug Wärmepumpe: ',        num2str(app.ERG(app.znr,32),'%0.0f'),' kWh/a'], '.', ','),...
                       strrep(['Netzbezug Elektrofahrzeug: ',   num2str(app.ERG(app.znr,33),'%0.0f'),' kWh/a'], '.', ',')};
            
            colors2 = [app.c.gelb , app.c.o , app.c.or , app.c.lila , app.c.dgruen1 , app.c.dgruen2 , app.c.hblau1, app.c.hblau2 , app.c.hblau3 , app.c.dgrau1 , app.c.dgrau2 , app.c.dgrau3];
            nonZeroIndexes2 = Data2 > 0;
            n2 = sum(nonZeroIndexes2);
            nonZeroIndexes2_c = repmat(nonZeroIndexes2,3,1);
            nonZeroIndexes2_c = nonZeroIndexes2_c(:)';
            colors2 = colors2(nonZeroIndexes2_c);
            colors2 = reshape(colors2,  3, n2)';
            labels2 = labels2(nonZeroIndexes2);
            pieData2 =  Data2; 
            pieData2 = pieData2(nonZeroIndexes2); % Nur positive Zahlen extrahieren
            app.p = pie(app.UIAxes2,pieData2);
            app.UIAxes2.Children; % Umrandung entfernen
            for i=(2:2:(n2*2))
            app.UIAxes2.Children(i).EdgeAlpha = 0; 
            end
            % Mittlerer Kreis
            hold(app.UIAxes2, 'on')
            app.p = plot(app.UIAxes2,0 , 0, '.', 'MarkerSize', 350, 'color', [0.94,0.94,0.94]);
            colormap(app.UIAxes2,colors2);
            app.UIAxes2.XLim=[-1.5 1.5];
            app.UIAxes2.YLim=app.UIAxes2.XLim;
            hold(app.UIAxes2, 'off')
            legend(app.UIAxes2,labels2,'Location','southoutside','Box','off','Color','none','FontSize',10)
            else
            cla(app.UIAxes2);
            end
            
            % 3. Balken Plot: Wirtschaftlichkeit
        
            Eur_j1 = Eur_s_j1-app.ERG(app.znr,12)*12-app.ERG(app.znr,13);
            J = [1, 2, 3, 4, 5, 6, 7, 8, 9, 10];
            Y = [round(Eur_j1,0) , round(Eur_j1-app.ERG(app.znr,12)*12+Eur_s_j1,0) , round(Eur_j1-app.ERG(app.znr,12)*24+Eur_s_j1*2,0) , round(Eur_j1-app.ERG(app.znr,12)*36+Eur_s_j1*3,0) , round(Eur_j1-app.ERG(app.znr,12)*48+Eur_s_j1*4,0) , round(Eur_j1-app.ERG(app.znr,12)*60+Eur_s_j1*5,0) , round(Eur_j1-app.ERG(app.znr,12)*72+Eur_s_j1*6,0) , round(Eur_j1-app.ERG(app.znr,12)*84+Eur_s_j1*7,0) , round(Eur_j1-app.ERG(app.znr,12)*96+Eur_s_j1*8,0) , round(Eur_j1-app.ERG(app.znr,12)*108+Eur_s_j1*9,0)];
            app.b = bar(app.UIAxes3,J,Y,0.3,'FaceColor', 'flat');
            xtips3 = app.b(1).XEndPoints;
            ytips3 = app.b(1).YEndPoints;
            labels3 = string(app.b(1).YData);
            text(app.UIAxes3,xtips3(ytips3 > 0),ytips3(ytips3 > 0),labels3(ytips3 > 0)+' €','Color','k','fontweight', 'bold','FontSize',8,'HorizontalAlignment','center','VerticalAlignment','bottom')
            text(app.UIAxes3,xtips3(ytips3 < 0),ytips3(ytips3 < 0),labels3(ytips3 < 0)+' €','Color','r','fontweight', 'bold','FontSize',8,'HorizontalAlignment','center','VerticalAlignment','top')
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ERG)
            % Import der Ergebnismatrix in den Matlab-Workspace (Achtung: Funktioniert nur, wenn in
            % Matlab unter "Current Folder" der Ordner aufgefuehrt ist, in
            % dem sich die Datei befindet.)
            app.S = load('Ergebnisse.mat','ERG_05'); 
            % Ergebnisvariable "ERG" erstellen
            app.ERG = app.S.ERG_05;
            
            % Vektoren und Schrittweiten der Variablen aus der Ergebnismatrix
            % extrahieren
            app.P_PV_v = unique(app.ERG(:,2));
            app.P_PV_i = app.P_PV_v(2) - app.P_PV_v(1);
            app.AZI_PV_v = unique(app.ERG(:,3));
            app.AZI_PV_i = app.AZI_PV_v(2) - app.AZI_PV_v(1);
            app.E_LE_v = unique(app.ERG(:,4));
            app.E_LE_i = app.E_LE_v(2) - app.E_LE_v(1); 
            app.E_BAT_v = unique(app.ERG(:,5));
            app.E_BAT_i = app.E_BAT_v(2) - app.E_BAT_v(1);                      
            app.Eclo_frei_v = unique(app.ERG(:,6));
            app.Eclo_frei_i = app.Eclo_frei_v(2) - app.Eclo_frei_v(1);
            app.HS_v = unique(app.ERG(:,7));
            app.HS_i = app.HS_v(2) - app.HS_v(1);
            app.E_WP_v = unique(app.ERG(:,8));
            app.E_WP_i = app.E_WP_v(2) - app.E_WP_v(1);
            app.S_BEV_v = unique(app.ERG(:,9));
            app.S_BEV_i = app.S_BEV_v(2) - app.S_BEV_v(1);
            app.Eur_ein_v = unique(app.ERG(:,10));
            app.Eur_ein_i = app.Eur_ein_v(2) - app.Eur_ein_v(1);
            app.Eur_bez_v = unique(app.ERG(:,11));
            app.Eur_bez_i = app.Eur_bez_v(2) - app.Eur_bez_v(1);
            app.Eur_lau_v = unique(app.ERG(:,12));
            app.Eur_lau_i = app.Eur_lau_v(2) - app.Eur_lau_v(1);
            app.Eur_inv_v = unique(app.ERG(:,13));
            app.Eur_inv_i = app.Eur_inv_v(2) - app.Eur_inv_v(1);
            
            % Startwerte der Slider beim Oeffnen der App definieren     
            app.P_PV_Slider.Value = 3;
            app.E_BAT_Slider.Value = 0;
            app.E_LE_Slider.Value = 0;
            app.E_WP_Slider.Value = 0;
            
            % Visibilität der Komponenten beim Oeffnen der App definieren 
            % Eigenverbrauch
            app.E_LE_Slider.Visible = 'off';
            app.E_LE_Slider_Label.Visible = 'off';
            app.Eur_bez_DropDown.Visible = 'off';
            app.Eur_bez_DropDown_Label.Visible = 'off';
            % Batteriesystem
            app.E_BAT_Slider.Visible = 'off';
            app.E_BAT_Slider_Label.Visible = 'off';
            app.E_BAT_socdur_Value.Visible = 'off';
            % Stromcloud
            app.Eclo_Switch.Visible = 'off';
            app.Eclo_Switch_Label.Visible = 'off';
            app.Eclo_frei_DropDown.Visible = 'off';
            app.Eclo_frei_DropDown_Label.Visible = 'off';
            app.Eclo_Value.Visible = 'off';
            % Heizstab
            app.HS_Value.Visible = 'off';
            % Wärmepumpe
            app.E_WP_Slider.Visible = 'off';
            app.E_WP_Slider_Label.Visible = 'off';
            % Elektrofahrzeug
            app.S_BEV_DropDown.Visible = 'off';
            app.S_BEV_DropDown_Label.Visible = 'off';
            app.S_BEV_Value.Visible = 'off';
            
            % Limits und Ticks der Slider anhand der in der Ergebnismatrix aufgefuehrten Werte anpassen
            app.P_PV_Slider.Limits=[app.P_PV_v(1) app.P_PV_v(end)];
            app.P_PV_Slider.MajorTicks=app.P_PV_v(1):app.P_PV_i:app.P_PV_v(end); 
            app.P_PV_Slider.MajorTickLabelsMode='auto';
            app.P_PV_Slider.MinorTicks=[];
            
            app.E_BAT_Slider.Limits=[app.E_BAT_v(1) app.E_BAT_v(end)];
            app.E_BAT_Slider.MajorTicks=app.E_BAT_v(1):app.E_BAT_i:app.E_BAT_v(end); 
            app.E_BAT_Slider.MajorTickLabelsMode='auto';
            app.E_BAT_Slider.MinorTicks=[];
            
            app.E_LE_Slider.Limits=[app.E_LE_v(1) app.E_LE_v(end)];
            app.E_LE_Slider.MajorTicks=app.E_LE_v(1):app.E_LE_i:app.E_LE_v(end); 
            app.E_LE_Slider.MajorTickLabelsMode='auto';
            app.E_LE_Slider.MinorTicks=[];
            
            app.E_WP_Slider.Limits=[app.E_WP_v(1) app.E_WP_v(end)];
            app.E_WP_Slider.MajorTicks=app.E_WP_v(1):app.E_WP_i:app.E_WP_v(end); 
            app.E_WP_Slider.MajorTickLabelsMode='auto';
            app.E_WP_Slider.MinorTicks=[];
            
            % Farben (RGB-Code) definieren
            app.c.hgruen = [118, 185, 0]./255; % Laden Batterie
            app.c.dgruen1 = [0.4660, 0.68, 0.15]; % Batteriebezug Hauslast
            app.c.dgruen2 = [0.4660, 0.58, 0.2]; % Batteriebezug WP
            app.c.grau = [195, 195, 195]./255;  % Netzeinspeisung PV
            app.c.dgrau1 = [95, 95, 95]./255; % Netzbezug Hauslast
            app.c.dgrau2 = [63, 63, 63]./255; % Netzbezug WP
            app.c.dgrau3 = [33, 33, 33]./255; % Netzbezug WP
            app.c.gelb = [255, 204, 0]./255;% Solardirektversorgung Hauslast
            app.c.blau = [0.3, 0.65, 0.9]; % Einspeisen Cloud
            app.c.hblau1 = [0.30, 0.85, 0.9]; % Cloudbezug Hauslast
        	app.c.hblau2 = [0.3010, 0.7450, 0.9330]; % Cloudbezug WP
            app.c.hblau3 = [0.3010, 0.6450, 0.960]; % Cloudbezug BEV
            app.c.o = [0.9290, 0.6, 0.1250]; % Solardirektversorgung WP
            app.c.or = [0.9290, 0.4, 0.1250]; % Solardirektversorgung HS
            app.c.lila = [0.4940, 0.1840, 0.5560]; % Solardirektversorgung BEV

             
        end

        % Value changed function: P_PV_Slider
        function P_PV_SliderValueChanged(app, event)
            % Slider-Wert muss unter Beruecksichtigung der vorhandenen Stuetzstellen angepasst werden
            app.P_PV_Slider.Value = round(app.P_PV_Slider.Value/app.P_PV_i)*app.P_PV_i;
            
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: AZI_PV_DropDown
        function AZI_PV_DropDownValueChanged(app, event)
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: E_LE_Slider, E_LE_Switch
        function E_LE_SliderValueChanged(app, event)
            % Slider-Wert muss unter Beruecksichtigung der vorhandenen Stuetzstellen angepasst werden
            app.E_LE_Slider.Value = round(app.E_LE_Slider.Value/app.E_LE_i)*app.E_LE_i;
            
            % Eigenschaften über EigenverbrauchSwitch
            if app.E_LE_Switch.Value == 0 
                
                app.E_BAT_Switch.Value = 0;
                app.S_BEV_Switch.Value = 0;
                app.E_WP_Switch.Value = 0;
                app.HS_Switch.Value = 0;
                
                % Ein/Ausblenden
                % Strombedarf
                app.E_LE_Slider.Visible = 'off'; % Slider
                app.E_LE_Slider_Label.Visible = 'off'; % Überschrift
                % Bezugspreis
                app.Eur_bez_DropDown.Visible = 'off'; % DropDown
                app.Eur_bez_DropDown_Label.Visible = 'off'; % Label
                % Werte Einstellungen
                % Strombedarf
                app.E_LE_Slider.Value = 0; % Slider
                % Bezugspreis
                app.Eur_bez_DropDown.Value = 30; % DropDown
            else
                % Ein/Ausblenden
                % Strombedarf
                app.E_LE_Slider.Visible = 'on'; % Slider
                app.E_LE_Slider_Label.Visible = 'on'; % Überschrift
                % Bezugspreis
                app.Eur_bez_DropDown.Visible = 'on'; % DropDown
                app.Eur_bez_DropDown_Label.Visible = 'on'; % Label
            end
            
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: E_BAT_Slider, E_BAT_Switch
        function E_BAT_SliderValueChanged(app, event)
            % Slider-Wert muss unter Beruecksichtigung der vorhandenen Stuetzstellen angepasst werden
            app.E_BAT_Slider.Value = round(app.E_BAT_Slider.Value/app.E_BAT_i)*app.E_BAT_i;
            
            % Eigenschaften über BatterieSwitch
            if app.E_BAT_Switch.Value == 0 
                % Ein/Ausblenden
                % Speicherkapazität
                app.E_BAT_Slider.Visible = 'off'; % Slider
                app.E_BAT_Slider_Label.Visible = 'off'; % Überschrift
                app.E_BAT_socdur_Value.Visible = 'off'; % Anzeige durchschnittlicher SOC
                % Cloud
                app.Eclo_Switch.Visible = 'off'; % Switch
                app.Eclo_frei_DropDown.Visible = 'off'; % DropDown
                app.Eclo_Value.Visible = 'off'; % Wert
                app.Eclo_frei_DropDown_Label.Visible = 'off'; % Label
                app.Eclo_Switch_Label.Visible = 'off'; % Label
                % Werte Einstellungen
                % Speicherkapazität
                app.E_BAT_Slider.Value = 0; % Slider
                % Cloud
                app.Eclo_Switch.Value = 0; % Switch
                app.Eclo_frei_DropDown.Value = 0; % DropDown
            else
                % Ein/Ausblenden
                % Speicherkapazität
                app.E_BAT_Slider.Visible = 'on'; % Slider
                app.E_BAT_Slider_Label.Visible = 'on'; % Überschrift
                app.E_BAT_socdur_Value.Visible = 'on'; % Anzeige durchschnittlicher SOC
                % Cloud
                app.Eclo_Switch.Visible = 'on'; % Switch
                app.Eclo_Switch_Label.Visible = 'on'; % Label
            end
            
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: Eclo_Switch, Eclo_frei_DropDown
        function Eclo_frei_DropDownValueChanged(app, event)
            
            % Eigenschaften über CloudSwitch
            if app.Eclo_Switch.Value == 0 
                % Ein/Ausblenden
                % Freistrommänge
                app.Eclo_frei_DropDown.Visible = 'off'; % Slider
                %app.E_BAT_Slider_Label.Visible = 'off'; % Überschrift
                app.Eclo_Value.Visible = 'off'; % Anzeige
                app.Eclo_frei_DropDown_Label.Visible = 'off'; % Label
                % Werte Einstellungen
                % Freistrommänge
                app.Eclo_frei_DropDown.Value = 0; % DropDown
            else
                % Ein/Ausblenden
                % Freistrommänge
                app.Eclo_frei_DropDown.Visible = 'on'; % DropDown
                app.Eclo_Value.Visible = 'on'; % Anzeige
                app.Eclo_frei_DropDown_Label.Visible = 'on'; % Label
            end
            
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: HS_Switch
        function HS_SwitchValueChanged(app, event)
             
            % Eigenschaften über HeizstabSwitch
            if app.HS_Switch.Value == 0 
                % Ein/Ausblenden
                % Nutzung Heizstab
                app.HS_Value.Visible = 'off'; % Anzeige
            else
                % Ein/Ausblenden
                % Nutzung Heizstab
                app.HS_Value.Visible = 'on'; % Anzeige
            end
            
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: E_WP_Slider, E_WP_Switch
        function E_WP_SliderValueChanged(app, event)
            % Slider-Wert muss unter Beruecksichtigung der vorhandenen Stuetzstellen angepasst werden
            app.E_WP_Slider.Value = round(app.E_WP_Slider.Value/app.E_WP_i)*app.E_WP_i;
            
            % Eigenschaften über WearmepumpeSwitch
            if app.E_WP_Switch.Value == 0 
                % Ein/Ausblenden
                % Strombedarf
                app.E_WP_Slider.Visible = 'off'; % Slider
                app.E_WP_Slider_Label.Visible = 'off'; % Überschrift
                % Werte Einstellungen
                % Strombedarf
                app.E_WP_Slider.Value = 0; % Slider
            else
                % Ein/Ausblenden
                % Strombedarf
                app.E_WP_Slider.Visible = 'on'; % Slider
                app.E_WP_Slider_Label.Visible = 'on'; % Überschrift
                
                % Werte Einstellungen
                % Heizstab ausschalten
                app.HS_Switch.Value = 0; % Slider
                
            end
                
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: S_BEV_DropDown, S_BEV_Switch
        function S_BEV_DropDownValueChanged(app, event)
            
            % Eigenschaften über S_BEV_Switch
            if app.S_BEV_Switch.Value == 0 
                % Ein/Ausblenden
                % Jährliche Farstrecke
                app.S_BEV_DropDown.Visible = 'off'; % Slider
                app.S_BEV_DropDown_Label.Visible = 'off'; % Überschrift
                app.S_BEV_Value.Visible = 'off'; % Jährlicher
                % Werte Einstellungen
                % Strombedarf
                app.S_BEV_DropDown.Value = 0; % Slider
            else
                % Ein/Ausblenden
                % Jährliche Farstrecke
                app.S_BEV_DropDown.Visible = 'on'; % Slider
                app.S_BEV_DropDown_Label.Visible = 'on'; % Überschrift
                app.S_BEV_Value.Visible = 'on'; % Jährlicher
            end
            
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: Eur_bez_DropDown
        function Eur_bez_DropDownValueChanged(app, event)
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: Eur_inv_DropDown
        function Eur_inv_DropDownValueChanged(app, event)
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: Eur_lau_DropDown
        function Eur_lau_DropDownValueChanged(app, event)
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end

        % Value changed function: Eur_ein_DropDown
        function Eur_ein_DropDownValueChanged(app, event)
            % Funktion zur Berechnung der Zeilennr. sowie zur
            % Visualisierung der Berechnungsergebnisse aufrufen
            func(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes3_Label = uilabel(app.UIFigure);
            app.UIAxes3_Label.Text = 'Wirtschaftlichkeit';
            app.Eur_cfw_Value = uilabel(app.UIFigure);
            app.Eur_cfw_Value.Text = '';
            app.Eur_s_Value = uilabel(app.UIFigure);
            app.Eur_s_Value.Text = '';
            app.E_LE_Slider_Label = uilabel(app.UIFigure);
            app.E_LE_Slider_Label.Text = 'Strombedarf in kWh/a';
            app.E_LE_Slider = uislider(app.UIFigure);
            app.E_LE_Slider.Limits = [0 5000];
            app.E_LE_Slider.MajorTicks = [0 1000 2000 3000 4000 5000];
            app.E_LE_Slider.MajorTickLabels = {'0 ', '1000 ', '2000 ', '3000 ', '4000 ', '5000 ', ''};
            app.E_LE_Slider.ValueChangedFcn = createCallbackFcn(app, @E_LE_SliderValueChanged, true);
            app.E_LE_Slider.MinorTicks = [];
            app.E_LE_Slider.Tooltip = {'Hier kann der jährliche Strombedarf ausgewählt werden, der im Gebäude anfällt'};
            app.Autarkiegrad_Value = uilabel(app.UIFigure);
            app.Autarkiegrad_Value.Text = '';
            app.Eigenverbrauchsanteil_Value = uilabel(app.UIFigure);
            app.Eigenverbrauchsanteil_Value.Text = '';
            app.Zeilennr_Value = uilabel(app.UIFigure);
            app.Zeilennr_Value.Visible = 'off';
            app.Zeilennr_Value.Text = '';
            app.P_PV_Slider_Label = uilabel(app.UIFigure);
            app.P_PV_Slider_Label.Text = 'PV-Generatorleistung in kWp';
            app.P_PV_Slider = uislider(app.UIFigure);
            app.P_PV_Slider.Limits = [1 5];
            app.P_PV_Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.P_PV_Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.P_PV_Slider.ValueChangedFcn = createCallbackFcn(app, @P_PV_SliderValueChanged, true);
            app.P_PV_Slider.MinorTicks = [];
            app.P_PV_Slider.Tooltip = {'Hier kann die PV-Generatorleistung der zu untersuchenden PV-Anlage ausgewählt werden'};
            app.P_PV_Slider.Value = 1;
            app.Ueberschrift_Label = uilabel(app.UIFigure);
            app.Ueberschrift_Label.Text = 'Untersuchung der Weiterbetriebsoptionen einer PV-Anlage nach 20 Betriebsjahren';
            app.Eur_bez_DropDown = uidropdown(app.UIFigure);
            app.Eur_bez_DropDown.Items = {'25 ct/kWh', '30 ct/kWh', '35 ct/kWh'};
            app.Eur_bez_DropDown.ItemsData = [25 30 35];
            app.Eur_bez_DropDown.ValueChangedFcn = createCallbackFcn(app, @Eur_bez_DropDownValueChanged, true);
            app.Eur_bez_DropDown.Tooltip = {'Hier kann der Strombezugspreis für aus dem öffentlichen Stromnetz bezogenen Stromes ausgewählt werden'};
            app.Eur_bez_DropDown.Value = 30;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Eur_inv_DropDown = uidropdown(app.UIFigure);
            app.Eur_inv_DropDown.Items = {'0 €', '1000 €', '3000 €', '5000 €', '7000 €', '10000 €'};
            app.Eur_inv_DropDown.ItemsData = [0 1000 3000 5000 7000 10000];
            app.Eur_inv_DropDown.ValueChangedFcn = createCallbackFcn(app, @Eur_inv_DropDownValueChanged, true);
            app.Eur_inv_DropDown.Tooltip = {'Hier können die einmaligen Investitionskosten in die PV-Anlage ausgewählt werden'};
            app.Eur_inv_DropDown.Value = 0;
            app.InvestitionenLabel = uilabel(app.UIFigure);
            app.InvestitionenLabel.Text = 'Investitionen';
            app.E_BAT_Slider_Label = uilabel(app.UIFigure);
            app.E_BAT_Slider_Label.Text = 'Batteriekapazität in kWh';
            app.E_BAT_Slider = uislider(app.UIFigure);
            app.E_BAT_Slider.Limits = [0 10];
            app.E_BAT_Slider.MajorTicks = [0 2.5 5 7.5 10];
            app.E_BAT_Slider.MajorTickLabels = {'0 ', '2.5 ', '5 ', '7.5 ', '10 '};
            app.E_BAT_Slider.ValueChangedFcn = createCallbackFcn(app, @E_BAT_SliderValueChanged, true);
            app.E_BAT_Slider.MinorTicks = [];
            app.E_BAT_Slider.Tooltip = {'Hier kann die Netto Batteriekapazität des Batteriespeichers ausgewählt werden'};
            app.Eur_lau_DropDown = uidropdown(app.UIFigure);
            app.Eur_lau_DropDown.Items = {'0 €/Monat', '20 €/Monat', '40 €/Monat', '60 €/Monat', '80 €/Monat'};
            app.Eur_lau_DropDown.ItemsData = [0 20 40 60 80];
            app.Eur_lau_DropDown.ValueChangedFcn = createCallbackFcn(app, @Eur_lau_DropDownValueChanged, true);
            app.Eur_lau_DropDown.Tooltip = {'Hier können die laufenden Kosten pro Monat der PV-Anlage ausgewählt werden'};
            app.Eur_lau_DropDown.Value = 0;
            app.LaufendeKostenLabel = uilabel(app.UIFigure);
            app.LaufendeKostenLabel.Text = 'Laufende Kosten';
            app.Eur_ein_DropDown = uidropdown(app.UIFigure);
            app.Eur_ein_DropDown.Items = {'0 ct/kWh', '2 ct/kWh', '3 ct/kWh', '4 ct/kWh'};
            app.Eur_ein_DropDown.ItemsData = [0 0.02 0.03 0.04];
            app.Eur_ein_DropDown.ValueChangedFcn = createCallbackFcn(app, @Eur_ein_DropDownValueChanged, true);
            app.Eur_ein_DropDown.Tooltip = {'Hier kann die Einspeisevergütung des in das öffentliche Stromnetz eingespeisten Stroms ausgewählt werden'};
            app.Eur_ein_DropDown.Value = 0;
            app.EinspeisevergtungLabel = uilabel(app.UIFigure);
            app.EinspeisevergtungLabel.Text = 'Einspeisevergütung';
            app.BatteriesystemLabel = uilabel(app.UIFigure);
            app.BatteriesystemLabel.Text = 'Batteriesystem';
            app.E_BAT_Switch = uiswitch(app.UIFigure, 'slider');
            app.E_BAT_Switch.Items = {'Nein', 'Ja'};
            app.E_BAT_Switch.ItemsData = [0 1];
            app.E_BAT_Switch.ValueChangedFcn = createCallbackFcn(app, @E_BAT_SliderValueChanged, true);
            app.E_BAT_Switch.Tooltip = {''};
            app.E_BAT_Switch.Value = 0;
            app.EigenverbrauchLabel = uilabel(app.UIFigure);
            app.EigenverbrauchLabel.Text = 'Eigenverbrauch';
            app.E_LE_Switch = uiswitch(app.UIFigure, 'slider');
            app.E_LE_Switch.Items = {'Nein', 'Ja'};
            app.E_LE_Switch.ItemsData = [0 1];
            app.E_LE_Switch.ValueChangedFcn = createCallbackFcn(app, @E_LE_SliderValueChanged, true);
            app.E_LE_Switch.Value = 0;
            app.Eur_bez_DropDown_Label = uilabel(app.UIFigure);
            app.Eur_bez_DropDown_Label.Text = 'Bezugspreis';
            app.AusrichtungLabel = uilabel(app.UIFigure);
            app.AusrichtungLabel.Text = 'Ausrichtung';
            app.AZI_PV_DropDown = uidropdown(app.UIFigure);
            app.AZI_PV_DropDown.Items = {'Ost', 'Süd', 'West'};
            app.AZI_PV_DropDown.ItemsData = [90 180 270];
            app.AZI_PV_DropDown.ValueChangedFcn = createCallbackFcn(app, @AZI_PV_DropDownValueChanged, true);
            app.AZI_PV_DropDown.Tooltip = {'Hier kann die Azimut Ausrichtung des PV-Generators ausgewählt werden'};
            app.AZI_PV_DropDown.Value = 180;
            app.Erzeugung_PV_Value = uilabel(app.UIFigure);
            app.Erzeugung_PV_Value.Text = '';
            app.Netzbezug_Value = uilabel(app.UIFigure);
            app.Netzbezug_Value.Text = '';
            app.Eclo_Value = uilabel(app.UIFigure);
            app.Eclo_Value.Text = '';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = '';
            app.Eclo_Switch = uiswitch(app.UIFigure, 'slider');
            app.Eclo_Switch.Items = {'Nein', 'Ja'};
            app.Eclo_Switch.ItemsData = [0 1];
            app.Eclo_Switch.ValueChangedFcn = createCallbackFcn(app, @Eclo_frei_DropDownValueChanged, true);
            app.Eclo_Switch.Value = 0;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Eclo_frei_DropDown = uidropdown(app.UIFigure);
            app.Eclo_frei_DropDown.Items = {'0 kWh/a', '1000 kWh/a', '2000 kWh/a', '3000 kWh/a', '4000 kWh/a'};
            app.Eclo_frei_DropDown.ItemsData = [0 1000 2000 3000 4000];
            app.Eclo_frei_DropDown.ValueChangedFcn = createCallbackFcn(app, @Eclo_frei_DropDownValueChanged, true);
            app.Eclo_frei_DropDown.Tooltip = {'Hier kann die zur Verfügung stehende jährliche Fristrommenge ausgewählt werden'};
            app.Eclo_frei_DropDown.Value = 0;
            app.Eclo_frei_DropDown_Label = uilabel(app.UIFigure);
            app.Eclo_frei_DropDown_Label.Text = 'Freistrommenge';
            app.Eclo_Switch_Label = uilabel(app.UIFigure);
            app.Eclo_Switch_Label.Text = 'Stromcloud';
            app.HeizstabSwitchLabel = uilabel(app.UIFigure);
            app.HeizstabSwitchLabel.Text = 'Heizstab';
            app.HS_Switch = uiswitch(app.UIFigure, 'slider');
            app.HS_Switch.Items = {'Nein', 'Ja'};
            app.HS_Switch.ItemsData = [0 1];
            app.HS_Switch.ValueChangedFcn = createCallbackFcn(app, @HS_SwitchValueChanged, true);
            app.HS_Switch.Value = 0;
            app.HS_Value = uilabel(app.UIFigure);
            app.HS_Value.Text = '';
            app.WrmepumpeSwitchLabel = uilabel(app.UIFigure);
            app.WrmepumpeSwitchLabel.Text = 'Wärmepumpe';
            app.E_WP_Switch = uiswitch(app.UIFigure, 'slider');
            app.E_WP_Switch.Items = {'Nein', 'Ja'};
            app.E_WP_Switch.ItemsData = [0 1];
            app.E_WP_Switch.ValueChangedFcn = createCallbackFcn(app, @E_WP_SliderValueChanged, true);
            app.E_WP_Switch.Value = 0;
            app.E_WP_Slider_Label = uilabel(app.UIFigure);
            app.E_WP_Slider_Label.Text = 'Strombedarf in kWh/a';
            app.E_WP_Slider = uislider(app.UIFigure);
            app.E_WP_Slider.Limits = [0 6000];
            app.E_WP_Slider.MajorTicks = [0 2000 4000 6000];
            app.E_WP_Slider.MajorTickLabels = {'0 ', '2000 ', '4000 ', '6000 ', ''};
            app.E_WP_Slider.ValueChangedFcn = createCallbackFcn(app, @E_WP_SliderValueChanged, true);
            app.E_WP_Slider.MinorTicks = [];
            app.E_WP_Slider.Tooltip = {'Hier kann der jährliche Strombedarf der Wärmepumpe ausgewählt werden'};
            app.Erzeugung_PV_spez_Value = uilabel(app.UIFigure);
            app.Erzeugung_PV_spez_Value.Text = '';
            app.E_BAT_socdur_Value = uilabel(app.UIFigure);
            app.E_BAT_socdur_Value.Text = '';
            app.ElektrofahrzeugSwitchLabel = uilabel(app.UIFigure);
            app.ElektrofahrzeugSwitchLabel.Text = 'Elektrofahrzeug';
            app.S_BEV_Switch = uiswitch(app.UIFigure, 'slider');
            app.S_BEV_Switch.Items = {'Nein', 'Ja'};
            app.S_BEV_Switch.ItemsData = [0 1];
            app.S_BEV_Switch.ValueChangedFcn = createCallbackFcn(app, @S_BEV_DropDownValueChanged, true);
            app.S_BEV_Switch.Value = 0;
            app.S_BEV_DropDown_Label = uilabel(app.UIFigure);
            app.S_BEV_DropDown_Label.Text = 'Fahrstrecke in km/a';
            app.S_BEV_Value = uilabel(app.UIFigure);
            app.S_BEV_Value.Text = '';
            app.S_BEV_DropDown = uidropdown(app.UIFigure);
            app.S_BEV_DropDown.Items = {'0 km', '8000 km', '12000 km', '16000 km'};
            app.S_BEV_DropDown.ItemsData = [0 8000 12000 16000];
            app.S_BEV_DropDown.ValueChangedFcn = createCallbackFcn(app, @S_BEV_DropDownValueChanged, true);
            app.S_BEV_DropDown.Tooltip = {'Hier kann die jährliche Fahrstrecke des Elektrofahrzeuges ausgewählt werden'};
            app.S_BEV_DropDown.Value = 0;
            app.UIAxes1 = uiaxes(app.UIFigure);
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XLim = [0.5 10.5];
            app.UIAxes3.XAxisLocation = 'origin';
            app.UIAxes3.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes3.XTickLabel = {'Jahr 1'; 'Jahr 2'; 'Jahr 3'; 'Jahr 4'; 'Jahr 5'; 'Jahr 6'; 'Jahr 7'; 'Jahr 8'; 'Jahr 9'; 'Jahr 10'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App05PVU20(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end